classdef runPsaProcessSimulationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CommandWindowMirrorTextArea  matlab.ui.control.TextArea
        CommandWindowMirrorTextAreaLabel  matlab.ui.control.Label
        StatusEditField              matlab.ui.control.EditField
        FolderNameEditField          matlab.ui.control.EditField
        FolderNameEditFieldLabel     matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        RestartButton                matlab.ui.control.Button
        httpswwwjkscottresearchgroupcomLabel  matlab.ui.control.Label
        PressureSwingAdsorptionProcessSimulatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        folderName % This is a folder name property in string        
    end         
    
    methods (Access = private)
        
        function funcGetWorkSpace(app)                                                
            %Turn off the diary
            diary off;            
            %Read the text file, rearrange it, and store it as a variable
            temp = regexp(fileread('myDiaryFile.txt'), '\r?\n', 'split');
            %Erase the last line which displays "diary off"
            temp(1:end-1);
            %Print the Work Space output to the text area
            app.CommandWindowMirrorTextArea.Value = temp;                  
            %Remove the diary
            delete('myDiaryFile.txt');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: FolderNameEditField, StatusEditField
        function FolderNameEditFieldValueChanging(app, event)
            %Grab the current folder name
            changingValue = event.Value;
            %Convert Char to String and save it inside the property called
            %folderName
            app.folderName = convertCharsToStrings(changingValue);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            %Make current instance of app invisible
            app.UIFigure.Visible = 'off';
            %Open 2nd instance of app
            runPsaProcessSimulationApp();  %Run runPsaProSimApp.mlapp
            %Delete old instance
            close(app.UIFigure) 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %Print message on the text edit field when the program runs
            app.StatusEditField.Value= 'Working...';
            %Pause to display the text before a long time operation
            pause(0.01);
            try
                %If there exists a previous diary
                if exist("myDiaryFile.txt", 'file')                     
                    %Turn off any diary
                    diary off;
                    %Delete the diary
                    delete("diary"); 
                end                                               
                %Save diary
                diary 'myDiaryFile.txt';
                %Call runPsaProcessSimulation.m and use property called 
                %folderName and provide it as an input.
                runPsaProcessSimulation(app.folderName);                                 
                %If the function ran correctly, then print out the work
                %space texts.
                funcGetWorkSpace(app);                
                %Finally, display that we are done with the function
                app.StatusEditField.Value = 'Done...';    
            catch e %e is an MException struct
                %Print the error identifier
                %fprintf(1,'The identifier was:\n%s',e.identifier);
                %Print the error message
                fprintf(1,'There was an error! The message was:\n%s',e.message);                                
                %If the function ran correctly, then print out the work
                %space texts.
                funcGetWorkSpace(app);                
                %Finally, display that we are done with the function
                app.StatusEditField.Value = 'Error...'; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PressureSwingAdsorptionProcessSimulatorLabel = uilabel(app.UIFigure);
            app.PressureSwingAdsorptionProcessSimulatorLabel.Text = {'Totally Open Pressure Swing Adsorption '; 'Intensification Laboratory (toPSAil)'};
            app.httpswwwjkscottresearchgroupcomLabel = uilabel(app.UIFigure);
            app.httpswwwjkscottresearchgroupcomLabel.Text = 'https://www.jkscottresearchgroup.com/';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FolderNameEditFieldLabel = uilabel(app.UIFigure);
            app.FolderNameEditFieldLabel.Text = 'Folder Name:';
            app.FolderNameEditField = uieditfield(app.UIFigure, 'text');
            app.FolderNameEditField.ValueChangingFcn = createCallbackFcn(app, @FolderNameEditFieldValueChanging, true);
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.ValueChangingFcn = createCallbackFcn(app, @FolderNameEditFieldValueChanging, true);
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Value = 'Folder Name?';
            app.CommandWindowMirrorTextAreaLabel = uilabel(app.UIFigure);
            app.CommandWindowMirrorTextAreaLabel.Text = 'Command Window (Mirror):';
            app.CommandWindowMirrorTextArea = uitextarea(app.UIFigure);
            app.CommandWindowMirrorTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = runPsaProcessSimulationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end